classdef ImageTransformation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ScaleYSlider       matlab.ui.control.Slider
        ScaleYSliderLabel  matlab.ui.control.Label
        ScaleXSlider       matlab.ui.control.Slider
        ScaleXSliderLabel  matlab.ui.control.Label
        RotateSlider       matlab.ui.control.Slider
        RotateSliderLabel  matlab.ui.control.Label
        Image              matlab.ui.control.Image
        LoadButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        image % Description
        original % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            f1 = fullfile(path,file);
            if prod(double(file) == 0) && prod(double(path) == 0)
                return
            end
            
            app.original = imread(f1);
            app.image = app.original;
            
            app.Image.ImageSource = app.image;
        end

        % Value changed function: RotateSlider
        function RotateSliderValueChanged(app, event)
            value = app.RotateSlider.Value;
            disp(value)
            app.image = rotate(app.image, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Image = uiimage(app.UIFigure);
            app.RotateSliderLabel = uilabel(app.UIFigure);
            app.RotateSliderLabel.Text = 'Rotate';
            app.RotateSlider = uislider(app.UIFigure);
            app.RotateSlider.MajorTicks = [0 30 45 60 90 120 135 150 180];
            app.RotateSlider.ValueChangedFcn = createCallbackFcn(app, @RotateSliderValueChanged, true);
            app.RotateSlider.MinorTicks = [0 3 6 9 12 15 18 21 24 27 30 33 36 39 42 45 48 51 54 57 60 63 66 69 72 75 78 81 84 87 90 93 96 100];
            app.ScaleXSliderLabel = uilabel(app.UIFigure);
            app.ScaleXSliderLabel.Text = 'Scale X';
            app.ScaleXSlider = uislider(app.UIFigure);
            app.ScaleYSliderLabel = uilabel(app.UIFigure);
            app.ScaleYSliderLabel.Text = 'Scale Y';
            app.ScaleYSlider = uislider(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageTransformation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end